classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReishandyscalculatorUIFigure  matlab.ui.Figure
        RemoveButton                  matlab.ui.control.Button
        CButton                       matlab.ui.control.Button
        Button_21                     matlab.ui.control.Button
        Button_20                     matlab.ui.control.Button
        Button_19                     matlab.ui.control.Button
        Button_18                     matlab.ui.control.Button
        Button_17                     matlab.ui.control.Button
        Button_16                     matlab.ui.control.Button
        Button_15                     matlab.ui.control.Button
        Button_14                     matlab.ui.control.Button
        xButton                       matlab.ui.control.Button
        Button_12                     matlab.ui.control.Button
        Button_11                     matlab.ui.control.Button
        Button_10                     matlab.ui.control.Button
        Button_9                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Equation = "0" % Equation in string
    end
    
    methods (Access = private)
        function updateEditField(app)
            app.Equation = char(app.Equation);

            if strcmp(app.Equation(1), '0') && length(app.Equation) > 1
                app.Equation = app.Equation(2:end)
            end

            displayEquation = strrep(app.Equation, '*(', "(")
            displayEquation = strrep(displayEquation, '*', 'x');

            app.EditField.Value = displayEquation;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Equation = app.Equation + "1";
            updateEditField(app);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Equation = app.Equation + "2";
            updateEditField(app);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Equation = app.Equation + "3";
            updateEditField(app);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.Equation = app.Equation + "4";
            updateEditField(app);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.Equation = app.Equation + "5";
            updateEditField(app);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.Equation = app.Equation + "6";
            updateEditField(app);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.Equation = app.Equation + "7";
            updateEditField(app);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.Equation = app.Equation + "8";
            updateEditField(app);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.Equation = app.Equation + "9";
            updateEditField(app);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.Equation = app.Equation + "0";
            updateEditField(app);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.Equation = app.Equation + "00";
            updateEditField(app);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.Equation = app.Equation + ".";
            updateEditField(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Equation = app.Equation + "*(";
            updateEditField(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Equation = app.Equation + ")";
            updateEditField(app);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Equation = app.Equation + "^";
            updateEditField(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Equation = app.Equation + "+";
            updateEditField(app);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Equation = app.Equation + "-";
            updateEditField(app);
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.Equation = app.Equation + "*";
            updateEditField(app);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.Equation = app.Equation + "/";
            updateEditField(app);
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            % TODO, solve the equation
            app.Equation = char(app.Equation);

            try
                result = eval(app.Equation);
                app.Equation = num2str(result);
            catch
                app.Equation = 'Error';
            end
            
            updateEditField(app);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.Equation = char(app.Equation); 

            if ~strcmp(app.Equation, '0') && ~isempty(app.Equation)
                if length(app.Equation) > 1
                    app.Equation = app.Equation(1:end-1);
                else
                    app.Equation = '0';
                end
            else
                app.Equation = '0';
            end
            
            updateEditField(app);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.Equation = "0";
            updateEditField(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReishandyscalculatorUIFigure = uifigure('Visible', 'off');
            app.ReishandyscalculatorUIFigure.Name = 'Reishandy''s calculator';
            app.EditField = uieditfield(app.ReishandyscalculatorUIFigure, 'text');
            app.EditField.Value = '0';
            app.Button = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '(';
            app.Button_2 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ')';
            app.Button_3 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '^';
            app.Button_5 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '1';
            app.Button_7 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '2';
            app.Button_8 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '3';
            app.Button_9 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '-';
            app.Button_10 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '4';
            app.Button_11 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '5';
            app.Button_12 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '6';
            app.xButton = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.Button_14 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '7';
            app.Button_15 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '8';
            app.Button_16 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '9';
            app.Button_17 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '/';
            app.Button_18 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '0';
            app.Button_19 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '00';
            app.Button_20 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '.';
            app.Button_21 = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '=';
            app.CButton = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.RemoveButton = uibutton(app.ReishandyscalculatorUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            % Show the figure after all components are created
            app.ReishandyscalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReishandyscalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReishandyscalculatorUIFigure)
        end
    end
end